classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        a                matlab.ui.control.Label
        CreateMavButton  matlab.ui.control.Button
        KillMavButton    matlab.ui.control.Button
        getZButton       matlab.ui.control.Button
    end


    properties (Access = private)
    end

    properties (Access = public)
        Zpos = 0; % Description
        MAV1
    end

    methods (Access = public)


        function MAV1=creatMav(app,mav)
            mav=MatMav(1);
            mav.set_UDPREMOTEADDR(1,{'192.168.1.144',2000})
            mav.set_UDPLOCALPRT(14550);
            mav.ConnectUDP();
            app.a.Value = mav.get_LocalNED(1).z
            
        end


    end


    methods (Access = private)

        % Button pushed function: getZButton
        function getZButtonPushed(app, event)
           app.ZEditField.value = app.Zpos
        end

        % Button pushed function: CreateMavButton
        function iniMAV(app, event)
         creatMav(app);
            
        end

        % Button pushed function: KillMavButton
        function killmav(app, event)
            mav.Disconnect();
            mav.delete();
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.a = uilabel(app.UIFigure);
            app.a.Text = 'Z';
            app.CreateMavButton = uibutton(app.UIFigure, 'push');
            app.CreateMavButton.ButtonPushedFcn = createCallbackFcn(app, @iniMAV, true);
            app.CreateMavButton.Text = 'Create Mav';
            app.KillMavButton = uibutton(app.UIFigure, 'push');
            app.KillMavButton.ButtonPushedFcn = createCallbackFcn(app, @killmav, true);
            app.KillMavButton.Text = 'Kill Mav';
            app.getZButton = uibutton(app.UIFigure, 'push');
            app.getZButton.ButtonPushedFcn = createCallbackFcn(app, @getZButtonPushed, true);
            app.getZButton.Text = 'get Z';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end